classdef RecorderInit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ARISDataAcquisitionUIFigure   matlab.ui.Figure
        SpectrogramsettingsPanel      matlab.ui.container.Panel
        WindowlengthEditFieldLabel    matlab.ui.control.Label
        WindowlengthEditField         matlab.ui.control.NumericEditField
        OverlapEditFieldLabel         matlab.ui.control.Label
        OverlapEditField              matlab.ui.control.NumericEditField
        MinimumvaluedBEditFieldLabel  matlab.ui.control.Label
        MinimumvaluedBEditField       matlab.ui.control.NumericEditField
        ColormapDropDownLabel         matlab.ui.control.Label
        ColormapDropDown              matlab.ui.control.DropDown
        FilesettingsPanel             matlab.ui.container.Panel
        RecorddataCheckBox            matlab.ui.control.CheckBox
        RecordpathButton              matlab.ui.control.Button
        FilelengthsEditFieldLabel     matlab.ui.control.Label
        FilelengthsEditField          matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        RecordsettingsPanel           matlab.ui.container.Panel
        SamplingfreqHzEditFieldLabel  matlab.ui.control.Label
        SamplingfreqHzEditField       matlab.ui.control.NumericEditField
        MaxdisplaystepEditFieldLabel  matlab.ui.control.Label
        MaxdisplaystepEditField       matlab.ui.control.NumericEditField
        RefreshtimesEditFieldLabel    matlab.ui.control.Label
        RefreshtimesEditField         matlab.ui.control.NumericEditField
        ChannellistListBoxLabel       matlab.ui.control.Label
        ChannellistListBox            matlab.ui.control.ListBox
        RefreshportsButton            matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        status                        matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        GPSPortDropDownLabel          matlab.ui.control.Label
        GPSPortDropDown               matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        handles;
        buff;
    end
    
    methods (Access = private)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label.Text='';
            app.status.Text='';
            % - Get available NI devices and list
            k=1;
            app.handles.devices=daq.getDevices();
            for j=1:length(app.handles.devices)
                app.handles.channels=app.handles.devices(j).Subsystems.ChannelNames;
                for i=1:length(app.handles.channels)
                    app.handles.channelslabel{k}=[app.handles.devices(j).ID '_' app.handles.channels{i}];
                    k=k+1;
                end
            end
            app.ChannellistListBox.Items=app.handles.channelslabel;
            % - Get available serial ports
            if isadmin()
                app.handles.gps=seriallist("all");
                app.GPSPortDropDown.Items=["No GPS" app.handles.gps];
            else
                app.status.Text={'To use the GPS feature,'; 'you must run MATLAB as administrator.'};
                app.GPSPortDropDown.Enable=0;
            end
        end

        % Button pushed function: RefreshportsButton
        function RefreshportsButtonPushed(app, event)
            % - Get available devices and list
            k=1;
            app.handles.devices=daq.getDevices();
            for j=1:length(app.handles.devices)
                app.handles.channels=app.handles.devices(j).Subsystems.ChannelNames;
                for i=1:length(app.handles.channels)
                    app.handles.channelslabel{k}=[app.handles.devices(j).ID '_' app.handles.channels{i}];
                    k=k+1;
                end
            end
            app.ChannellistListBox.Items=app.handles.channelslabel;
            % - Get available serial ports
            if isadmin()
                app.handles.gps=seriallist("all");
                app.GPSPortDropDown.Items=["No GPS" app.handles.gps];
            else
                app.status.Text={'To use the GPS feature,'; 'you must run MATLAB as administrator.'};
                app.GPSPortDropDown.Enable=0;
            end
        end

        % Button pushed function: RecordpathButton
        function RecordpathButtonPushed(app, event)
            app.buff = uigetdir;
            app.Label.Text = app.buff;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            clear maincalc;
            app.status.Text='Checking variables...';
            app.StartButton.Enable=0;
            drawnow;
            app.handles.root=app.buff;
            app.handles.wlen=app.WindowlengthEditField.Value;
            app.handles.overlap=app.OverlapEditField.Value;
            app.handles.dBlim=app.MinimumvaluedBEditField.Value;
            app.handles.map=app.ColormapDropDown.Value;
            app.handles.filestat=app.RecorddataCheckBox.Value;
            app.handles.timelimit=seconds(app.FilelengthsEditField.Value);
            app.handles.fs=app.SamplingfreqHzEditField.Value;
            app.handles.maxstep=app.MaxdisplaystepEditField.Value;
            app.handles.time=app.RefreshtimesEditField.Value;
            app.handles.channels=app.ChannellistListBox.Value;
            app.handles.nchannel=length(app.handles.channels);
            
            if isempty(app.buff) && app.handles.filestat==1
                app.status.Text='Choose a path';
                app.StartButton.Enable=1;
                return
            end 
            
            if ~strcmp(app.GPSPortDropDown.Value,"No GPS")
                try
                    instrreset;                    
                    app.handles.gps=serial(app.GPSPortDropDown.Value);
                    app.handles.gps.BaudRate=4800;
                    app.handles.gps.BytesAvailableFcn={@GPS_Interrupt,app.handles};
                    fopen(app.handles.gps);
                    app.handles.logger=fopen([app.handles.root '\logger.txt'],'w');
                catch
                    app.status.Text='GPS Error';
                    app.StartButton.Enable=1;
                    return;
                end
            end
            
            if app.handles.nchannel==0
                app.status.Text='Select at least one channel';
                app.StartButton.Enable=1;
                return;
            end
            
            app.status.Text='Starting NI device...';
            drawnow;
            try
                app.handles.ai=daq.createSession('ni');
                app.handles.ai.IsContinuous=true;
                app.handles.ai.Rate=app.handles.fs;
                app.handles.ai.NotifyWhenDataAvailableExceeds = app.handles.fs*app.handles.time;
                for i=1:app.handles.nchannel
                    [ID,channel]=strtok(app.handles.channels{i},'_');
                    addAnalogInputChannel(app.handles.ai,ID,str2double(channel(end)),'Voltage');
                end
                app.handles.lh = addlistener(app.handles.ai,'DataAvailable',@(src,event) maincalc(event,app.handles));
            catch
                clear ai;
                app.status.Text='NI device error';
                app.StartButton.Enable=1;
                return;
            end
            app.status.Text='Successfully started';
            
            if isfield(app.handles,'ui')
                if isvalid(app.handles.ui)
                    close(app.handles.ui);
                end
                delete(app.handles.ui);
            end
            app.handles.ui=maincalc(0,app.handles);
            app.StartButton.Visible=0;
            app.StartButton.Enable=1;
            app.StopButton.Visible=1;
            tic();
            startBackground(app.handles.ai);
            app.handles.ai.wait(2592000); % 30 Gün
            StopButtonPushed(app, 0);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            delete(app.handles.lh);
            stop(app.handles.ai);
            release(app.handles.ai)
            if ~strcmp(app.GPSPortDropDown.Value,"No GPS")
                instrreset;
            end
            if app.handles.filestat
                fclose all;
            end
            app.StartButton.Visible=1;
            app.StopButton.Visible=0;
            app.status.Text='';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ARISDataAcquisitionUIFigure = uifigure;
            app.ARISDataAcquisitionUIFigure.Name = 'ARIS Data Acquisition';
            app.SpectrogramsettingsPanel = uipanel(app.ARISDataAcquisitionUIFigure);
            app.SpectrogramsettingsPanel.Title = 'Spectrogram settings';
            app.WindowlengthEditFieldLabel = uilabel(app.SpectrogramsettingsPanel);
            app.WindowlengthEditFieldLabel.Text = 'Window length :';
            app.WindowlengthEditField = uieditfield(app.SpectrogramsettingsPanel, 'numeric');
            app.WindowlengthEditField.Limits = [1 Inf];
            app.WindowlengthEditField.RoundFractionalValues = 'on';
            app.WindowlengthEditField.ValueDisplayFormat = '%.0f';
            app.WindowlengthEditField.Value = 1024;
            app.OverlapEditFieldLabel = uilabel(app.SpectrogramsettingsPanel);
            app.OverlapEditFieldLabel.Text = 'Overlap :';
            app.OverlapEditField = uieditfield(app.SpectrogramsettingsPanel, 'numeric');
            app.OverlapEditField.Limits = [0 Inf];
            app.OverlapEditField.Value = 768;
            app.MinimumvaluedBEditFieldLabel = uilabel(app.SpectrogramsettingsPanel);
            app.MinimumvaluedBEditFieldLabel.Text = 'Minimum value [dB] :';
            app.MinimumvaluedBEditField = uieditfield(app.SpectrogramsettingsPanel, 'numeric');
            app.MinimumvaluedBEditField.Limits = [-Inf 0];
            app.MinimumvaluedBEditField.Value = -150;
            app.ColormapDropDownLabel = uilabel(app.SpectrogramsettingsPanel);
            app.ColormapDropDownLabel.Text = 'Colormap :';
            app.ColormapDropDown = uidropdown(app.SpectrogramsettingsPanel);
            app.ColormapDropDown.Items = {'Jet', 'Hot', 'HSV', 'Parula', 'Cool', 'Spring', 'Summer', 'Autumn', 'Winter', 'Gray', 'Bone', 'Copper', 'Pink', 'Thermal', 'Haline', 'Solar', 'Ice', 'Oxy', 'Deep', 'Dense', 'Algae', 'Matter', 'Turbid', 'Speed', 'Amp', 'Tempo', 'Rain', 'Balance', 'Delta', 'Curl', 'Diff', 'Tarn', 'Phase', 'Topo'};
            app.ColormapDropDown.Value = 'Jet';
            app.FilesettingsPanel = uipanel(app.ARISDataAcquisitionUIFigure);
            app.FilesettingsPanel.Title = 'File settings';
            app.RecorddataCheckBox = uicheckbox(app.FilesettingsPanel);
            app.RecorddataCheckBox.Text = 'Record data';
            app.RecordpathButton = uibutton(app.FilesettingsPanel, 'push');
            app.RecordpathButton.ButtonPushedFcn = createCallbackFcn(app, @RecordpathButtonPushed, true);
            app.RecordpathButton.Text = 'Record path';
            app.FilelengthsEditFieldLabel = uilabel(app.FilesettingsPanel);
            app.FilelengthsEditFieldLabel.Text = 'File length [s] :';
            app.FilelengthsEditField = uieditfield(app.FilesettingsPanel, 'numeric');
            app.FilelengthsEditField.Limits = [60 Inf];
            app.FilelengthsEditField.RoundFractionalValues = 'on';
            app.FilelengthsEditField.ValueDisplayFormat = '%.0f';
            app.FilelengthsEditField.Value = 120;
            app.Label = uilabel(app.FilesettingsPanel);
            app.RecordsettingsPanel = uipanel(app.ARISDataAcquisitionUIFigure);
            app.RecordsettingsPanel.Title = 'Record settings';
            app.SamplingfreqHzEditFieldLabel = uilabel(app.RecordsettingsPanel);
            app.SamplingfreqHzEditFieldLabel.Text = 'Sampling freq [Hz] :';
            app.SamplingfreqHzEditField = uieditfield(app.RecordsettingsPanel, 'numeric');
            app.SamplingfreqHzEditField.Limits = [1 Inf];
            app.SamplingfreqHzEditField.RoundFractionalValues = 'on';
            app.SamplingfreqHzEditField.ValueDisplayFormat = '%.0f';
            app.SamplingfreqHzEditField.Value = 10000;
            app.MaxdisplaystepEditFieldLabel = uilabel(app.RecordsettingsPanel);
            app.MaxdisplaystepEditFieldLabel.Text = 'Max display step :';
            app.MaxdisplaystepEditField = uieditfield(app.RecordsettingsPanel, 'numeric');
            app.MaxdisplaystepEditField.Limits = [1 Inf];
            app.MaxdisplaystepEditField.RoundFractionalValues = 'on';
            app.MaxdisplaystepEditField.ValueDisplayFormat = '%.0f';
            app.MaxdisplaystepEditField.Value = 30;
            app.RefreshtimesEditFieldLabel = uilabel(app.RecordsettingsPanel);
            app.RefreshtimesEditFieldLabel.Text = 'Refresh time [s] : ';
            app.RefreshtimesEditField = uieditfield(app.RecordsettingsPanel, 'numeric');
            app.RefreshtimesEditField.Limits = [0.1 Inf];
            app.RefreshtimesEditField.Value = 1;
            app.ChannellistListBoxLabel = uilabel(app.RecordsettingsPanel);
            app.ChannellistListBoxLabel.Text = 'Channel list';
            app.ChannellistListBox = uilistbox(app.RecordsettingsPanel);
            app.ChannellistListBox.Items = {};
            app.ChannellistListBox.Multiselect = 'on';
            app.ChannellistListBox.Value = {};
            app.RefreshportsButton = uibutton(app.RecordsettingsPanel, 'push');
            app.RefreshportsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshportsButtonPushed, true);
            app.RefreshportsButton.Text = 'Refresh ports';
            app.StartButton = uibutton(app.ARISDataAcquisitionUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.status = uilabel(app.ARISDataAcquisitionUIFigure);
            app.status.Text = 'Label2';
            app.StopButton = uibutton(app.ARISDataAcquisitionUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.GPSPortDropDownLabel = uilabel(app.ARISDataAcquisitionUIFigure);
            app.GPSPortDropDownLabel.Text = 'GPS Port :';
            app.GPSPortDropDown = uidropdown(app.ARISDataAcquisitionUIFigure);
            app.GPSPortDropDown.Items = {'No GPS'};
            app.GPSPortDropDown.Tooltip = {'You need to run MATLAB as administrator for using GPS functionality.'};
            app.GPSPortDropDown.Value = 'No GPS';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RecorderInit

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ARISDataAcquisitionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ARISDataAcquisitionUIFigure)
        end
    end
end