classdef RecorderInit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ARISDataAcquisitionUIFigure   matlab.ui.Figure
        SpectrogramsettingsPanel      matlab.ui.container.Panel
        WindowlengthEditFieldLabel    matlab.ui.control.Label
        WindowlengthEditField         matlab.ui.control.NumericEditField
        OverlapEditFieldLabel         matlab.ui.control.Label
        OverlapEditField              matlab.ui.control.NumericEditField
        MinimumvaluedBEditFieldLabel  matlab.ui.control.Label
        MinimumvaluedBEditField       matlab.ui.control.NumericEditField
        ColormapDropDownLabel         matlab.ui.control.Label
        ColormapDropDown              matlab.ui.control.DropDown
        FilesettingsPanel             matlab.ui.container.Panel
        RecorddataCheckBox            matlab.ui.control.CheckBox
        RecordpathButton              matlab.ui.control.Button
        FilelengthsEditFieldLabel     matlab.ui.control.Label
        FilelengthsEditField          matlab.ui.control.NumericEditField
        Label                         matlab.ui.control.Label
        RecordsettingsPanel           matlab.ui.container.Panel
        SamplingfreqHzEditFieldLabel  matlab.ui.control.Label
        SamplingfreqHzEditField       matlab.ui.control.NumericEditField
        MaxdisplaystepEditFieldLabel  matlab.ui.control.Label
        MaxdisplaystepEditField       matlab.ui.control.NumericEditField
        RefreshtimesEditFieldLabel    matlab.ui.control.Label
        RefreshtimesEditField         matlab.ui.control.NumericEditField
        ChannellistListBoxLabel       matlab.ui.control.Label
        ChannellistListBox            matlab.ui.control.ListBox
        RefreshButton                 matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        status                        matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        handles;
    end
    
    methods (Access = private)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label.Text='';
            app.status.Text='';
            % - Get available devices and list
            app.handles.devices=daq.getDevices();
            app.handles.channels=app.handles.devices.Subsystems.ChannelNames;
            app.ChannellistListBox.Items=app.handles.channels;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % - Get available devices and list
            app.handles.devices=daq.getDevices();
            app.handles.channels=app.handles.devices.Subsystems.ChannelNames;
            app.ChannellistListBox.Items=app.handles.channels;
        end

        % Button pushed function: RecordpathButton
        function RecordpathButtonPushed(app, event)
            app.handles.root = uigetdir;
            app.Label.Text = app.handles.root;            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            clear maincalc;
            app.status.Text='Checking variables...';
            app.StartButton.Enable=0;
            drawnow;
            app.handles.wlen=app.WindowlengthEditField.Value;
            app.handles.overlap=app.OverlapEditField.Value;
            app.handles.dBlim=app.MinimumvaluedBEditField.Value;
            app.handles.map=app.ColormapDropDown.Value;
            app.handles.filestat=app.RecorddataCheckBox.Value;
            app.handles.timelimit=seconds(app.FilelengthsEditField.Value);
            app.handles.fs=app.SamplingfreqHzEditField.Value;
            app.handles.maxstep=app.MaxdisplaystepEditField.Value;
            app.handles.time=app.RefreshtimesEditField.Value;
            app.handles.channels=app.ChannellistListBox.Value;
            app.handles.nchannel=length(app.handles.channels);
        
            app.status.Text='Starting NI device...';
            drawnow;
            try
                app.handles.ai=daq.createSession('ni');
                app.handles.ai.IsContinuous=true;
                app.handles.ai.Rate=app.handles.fs;
                app.handles.ai.NotifyWhenDataAvailableExceeds = app.handles.fs*app.handles.time;
                addAnalogInputChannel(app.handles.ai,'Dev4',0:3,'Voltage');
                app.handles.lh = addlistener(app.handles.ai,'DataAvailable',@(src,event) maincalc(event,app.handles));
            catch
                clear ai;
                app.status.Text='NI device error';
                app.StartButton.Enable=1;
                return;
            end
            app.status.Text='Success';
            
            app.handles.ui=maincalc(0,app.handles);
            app.StartButton.Visible=0;
            app.StartButton.Enable=1;
            app.StopButton.Visible=1;
            tic();
            startBackground(app.handles.ai);
            app.handles.ai.wait(2592000); % 30 Gün
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            delete(app.handles.lh);
            close(app.handles.ui);
            stop(app.handles.ai);
            release(app.handles.ai)
            if app.handles.filestat
                fclose all;
            end
            app.StartButton.Visible=1;
            app.StopButton.Visible=0;
            app.status.Text='';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ARISDataAcquisitionUIFigure = uifigure;
            app.ARISDataAcquisitionUIFigure.Name = 'ARIS Data Acquisition';
            app.SpectrogramsettingsPanel = uipanel(app.ARISDataAcquisitionUIFigure);
            app.SpectrogramsettingsPanel.Title = 'Spectrogram settings';
            app.WindowlengthEditFieldLabel = uilabel(app.SpectrogramsettingsPanel);
            app.WindowlengthEditFieldLabel.Text = 'Window length :';
            app.WindowlengthEditField = uieditfield(app.SpectrogramsettingsPanel, 'numeric');
            app.WindowlengthEditField.Limits = [1 Inf];
            app.WindowlengthEditField.RoundFractionalValues = 'on';
            app.WindowlengthEditField.ValueDisplayFormat = '%.0f';
            app.WindowlengthEditField.Value = 128;
            app.OverlapEditFieldLabel = uilabel(app.SpectrogramsettingsPanel);
            app.OverlapEditFieldLabel.Text = 'Overlap :';
            app.OverlapEditField = uieditfield(app.SpectrogramsettingsPanel, 'numeric');
            app.OverlapEditField.Limits = [0 Inf];
            app.OverlapEditField.Value = 96;
            app.MinimumvaluedBEditFieldLabel = uilabel(app.SpectrogramsettingsPanel);
            app.MinimumvaluedBEditFieldLabel.Text = 'Minimum value [dB] :';
            app.MinimumvaluedBEditField = uieditfield(app.SpectrogramsettingsPanel, 'numeric');
            app.MinimumvaluedBEditField.Limits = [-Inf 0];
            app.MinimumvaluedBEditField.Value = -150;
            app.ColormapDropDownLabel = uilabel(app.SpectrogramsettingsPanel);
            app.ColormapDropDownLabel.Text = 'Colormap :';
            app.ColormapDropDown = uidropdown(app.SpectrogramsettingsPanel);
            app.ColormapDropDown.Items = {'Jet', 'Hot'};
            app.ColormapDropDown.Value = 'Jet';
            app.FilesettingsPanel = uipanel(app.ARISDataAcquisitionUIFigure);
            app.FilesettingsPanel.Title = 'File settings';
            app.RecorddataCheckBox = uicheckbox(app.FilesettingsPanel);
            app.RecorddataCheckBox.Text = 'Record data';
            app.RecordpathButton = uibutton(app.FilesettingsPanel, 'push');
            app.RecordpathButton.ButtonPushedFcn = createCallbackFcn(app, @RecordpathButtonPushed, true);
            app.RecordpathButton.Text = 'Record path';
            app.FilelengthsEditFieldLabel = uilabel(app.FilesettingsPanel);
            app.FilelengthsEditFieldLabel.Text = 'File length [s] :';
            app.FilelengthsEditField = uieditfield(app.FilesettingsPanel, 'numeric');
            app.FilelengthsEditField.Limits = [60 Inf];
            app.FilelengthsEditField.RoundFractionalValues = 'on';
            app.FilelengthsEditField.ValueDisplayFormat = '%.0f';
            app.FilelengthsEditField.Value = 120;
            app.Label = uilabel(app.FilesettingsPanel);
            app.RecordsettingsPanel = uipanel(app.ARISDataAcquisitionUIFigure);
            app.RecordsettingsPanel.Title = 'Record settings';
            app.SamplingfreqHzEditFieldLabel = uilabel(app.RecordsettingsPanel);
            app.SamplingfreqHzEditFieldLabel.Text = 'Sampling freq [Hz] :';
            app.SamplingfreqHzEditField = uieditfield(app.RecordsettingsPanel, 'numeric');
            app.SamplingfreqHzEditField.Limits = [1 Inf];
            app.SamplingfreqHzEditField.RoundFractionalValues = 'on';
            app.SamplingfreqHzEditField.ValueDisplayFormat = '%.0f';
            app.SamplingfreqHzEditField.Value = 10000;
            app.MaxdisplaystepEditFieldLabel = uilabel(app.RecordsettingsPanel);
            app.MaxdisplaystepEditFieldLabel.Text = 'Max display step :';
            app.MaxdisplaystepEditField = uieditfield(app.RecordsettingsPanel, 'numeric');
            app.MaxdisplaystepEditField.Limits = [1 Inf];
            app.MaxdisplaystepEditField.RoundFractionalValues = 'on';
            app.MaxdisplaystepEditField.ValueDisplayFormat = '%.0f';
            app.MaxdisplaystepEditField.Value = 10;
            app.RefreshtimesEditFieldLabel = uilabel(app.RecordsettingsPanel);
            app.RefreshtimesEditFieldLabel.Text = 'Refresh time [s] : ';
            app.RefreshtimesEditField = uieditfield(app.RecordsettingsPanel, 'numeric');
            app.RefreshtimesEditField.Limits = [0.1 Inf];
            app.RefreshtimesEditField.Value = 1;
            app.ChannellistListBoxLabel = uilabel(app.RecordsettingsPanel);
            app.ChannellistListBoxLabel.Text = 'Channel list';
            app.ChannellistListBox = uilistbox(app.RecordsettingsPanel);
            app.ChannellistListBox.Items = {};
            app.ChannellistListBox.Multiselect = 'on';
            app.ChannellistListBox.Value = {};
            app.RefreshButton = uibutton(app.RecordsettingsPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.StartButton = uibutton(app.ARISDataAcquisitionUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.status = uilabel(app.ARISDataAcquisitionUIFigure);
            app.status.Text = 'Label2';
            app.StopButton = uibutton(app.ARISDataAcquisitionUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RecorderInit

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ARISDataAcquisitionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ARISDataAcquisitionUIFigure)
        end
    end
end